classdef display_3D_curve_all < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        slan_CM_type                matlab.ui.control.EditField
        invert                      matlab.ui.control.CheckBox
        ColormapDropDown            matlab.ui.control.DropDown
        ColormapDropDownLabel       matlab.ui.control.Label
        VertCheck                   matlab.ui.control.CheckBox
        HorzCheck                   matlab.ui.control.CheckBox
        IndexCheck                  matlab.ui.control.CheckBox
        sigma                       matlab.ui.control.NumericEditField
        loadtifButton               matlab.ui.control.Button
        v_index                     matlab.ui.control.NumericEditField
        h_index                     matlab.ui.control.NumericEditField
        DataMatrixDropDown          matlab.ui.control.DropDown
        DataMatrixDropDownLabel     matlab.ui.control.Label
        verticalaxisSlider          matlab.ui.control.Slider
        verticalaxisSliderLabel     matlab.ui.control.Label
        horrizontalaxisSlider       matlab.ui.control.Slider
        horrizontalaxisSliderLabel  matlab.ui.control.Label
        loadButton                  matlab.ui.control.Button
        slice                       matlab.ui.control.NumericEditField
        saveButton                  matlab.ui.control.Button
        smoothSwitch                matlab.ui.control.ToggleSwitch
        smoothSwitchLabel           matlab.ui.control.Label
        EditField                   matlab.ui.control.EditField
        DimensionDropDown           matlab.ui.control.DropDown
        DimensionDropDownLabel      matlab.ui.control.Label
        sliceindexSlider            matlab.ui.control.Slider
        sliceindexSliderLabel       matlab.ui.control.Label
        vertical                    matlab.ui.control.UIAxes
        horrizontal                 matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        phase=[]; % Description
        path=[];
        name=[];
        data0=[];   %use this to record the images plotted now.
        f3=[];  %use f3 as the determination for which matrix data to load.
        loadtifpath='C:\Runze\results';
        matrix_min=[];
        matrix_max=[];


        % define colormap.
        water_density_blue=[0.968627000000000	0.984314000000000	1
            0.965552000000000	0.982345000000000	0.999016000000000
            0.962476000000000	0.980377000000000	0.998032000000000
            0.959400000000000	0.978408000000000	0.997047000000000
            0.956324000000000	0.976440000000000	0.996063000000000
            0.953249000000000	0.974471000000000	0.995079000000000
            0.950173000000000	0.972503000000000	0.994095000000000
            0.947097000000000	0.970534000000000	0.993110000000000
            0.944022000000000	0.968566000000000	0.992126000000000
            0.940946000000000	0.966597000000000	0.991142000000000
            0.937870000000000	0.964629000000000	0.990158000000000
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            0.0313730000000000	0.188235000000000	0.419608000000000];
        water_orientation=[0	0	1
            0.00917431192660551	0.00917431192660551	1
            0.0183486238532110	0.0183486238532110	1
            0.0275229357798165	0.0275229357798165	1
            0.0366972477064220	0.0366972477064220	1
            0.0458715596330275	0.0458715596330275	1
            0.0550458715596330	0.0550458715596330	1
            0.0642201834862385	0.0642201834862385	1
            0.0733944954128440	0.0733944954128440	1
            0.0825688073394496	0.0825688073394496	1
            0.0917431192660551	0.0917431192660551	1
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            1	0	0];
        water_orientation_narrow=[0	0	1
            0.00787401574803150	0.00787401574803150	1
            0.0157480314960630	0.0157480314960630	1
            0.0236220472440945	0.0236220472440945	1
            0.0314960629921260	0.0314960629921260	1
            0.0393700787401575	0.0393700787401575	1
            0.0472440944881890	0.0472440944881890	1
            0.0551181102362205	0.0551181102362205	1
            0.0629921259842520	0.0629921259842520	1
            0.0708661417322835	0.0708661417322835	1
            0.0787401574803150	0.0787401574803150	1
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            1	0	0];
        colormap_slanCM=[];
    end

    
    methods (Access = private)
        % y z x
        function plot3D(app)
            value=round(app.sliceindexSlider.Value,0);
            phase0=app.phase;
            if app.DimensionDropDown.Value=='1'
                app.data0=permute(squeeze(phase0(value,:,:)),[2 1]);
                imagesc(app.UIAxes,app.data0);
                xlabel(app.UIAxes,'x');
                ylabel(app.UIAxes,'z');
                axis(app.UIAxes,"xy");
            end
            if app.DimensionDropDown.Value=='2'
                app.data0=permute(squeeze(phase0(:,value,:)),[2 1]);
                imagesc(app.UIAxes,app.data0);
                xlabel(app.UIAxes,'y');
                ylabel(app.UIAxes,'z');
                axis(app.UIAxes,"xy");
            end
            if app.DimensionDropDown.Value=='3'
                app.data0=squeeze(phase0(:,:,value));
                if app.smoothSwitch.Value==1
                    phase1=squeeze(app.data0);
                    phase2=interp2(phase1,4);
                    phase3=smoothdata(phase2,'gaussian');
                else
                    phase3=app.data0;
                end
                imagesc(app.UIAxes,phase3);
                xlabel(app.UIAxes,'x');
                ylabel(app.UIAxes,'y');
                axis(app.UIAxes,"xy");
            end
            axis(app.UIAxes,"tight");
            if app.DimensionDropDown.Value==3
                axis(app.UIAxes,"equal");
            else
                axis(app.UIAxes,"square");
            end

            % choose color.
            if app.invert.Value==0
                if app.ColormapDropDown.Value=="turbo"
                    colormap(app.UIAxes,'turbo');
                end
                if app.ColormapDropDown.Value=="water_density_blue"
                    colormap(app.UIAxes,app.water_density_blue);
                end
                if app.ColormapDropDown.Value=="water_orientation"
                    colormap(app.UIAxes,app.water_orientation);
                end
                if app.ColormapDropDown.Value=="water_orientation_narrow"
                    colormap(app.UIAxes,app.water_orientation_narrow);
                end
                if app.ColormapDropDown.Value=="slan_CM"
                    colormap(app.UIAxes,app.colormap_slanCM);
                end
            else
                if app.ColormapDropDown.Value=="turbo"
                    colormap(app.UIAxes,'turbo');
                    colormap(app.UIAxes,flipud(colormap(app.UIAxes)));
                end
                if app.ColormapDropDown.Value=="water_density_blue"
                    colormap(app.UIAxes,flipud(app.water_density_blue));
                end
                if app.ColormapDropDown.Value=="water_orientation"
                    colormap(app.UIAxes,flipud(app.water_orientation));
                end
                if app.ColormapDropDown.Value=="water_orientation_narrow"
                    colormap(app.UIAxes,flipud(app.water_orientation_narrow));
                end
                if app.ColormapDropDown.Value=="slan_CM"
                    colormap(app.UIAxes,flipud(app.colormap_slanCM));
                end
            end


            % maintain the color limits.
            clim(app.UIAxes,[app.matrix_min,app.matrix_max]'.*app.sigma.Value);
%             switch app.f3
%                 case 0
%                     title(app.UIAxes,'Force');
%                 case 1
%                     title(app.UIAxes,'Amplitude');
%                 case 2
%                     title(app.UIAxes,'Phase');
%                 case 3
%                     title(app.UIAxes,'Modulation');
%                 case 4
%                     title(app.UIAxes,'X position');
%                     %the x_app_m data might be differnt for 2D structure,
%                     %do it later.
%             end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %since I'm already using app.phase to record the data, keep
            %using it. switch between different values.
%             parad=evalin('base','parad');
%             app.f3=parad.f3;
             app.path=evalin('base','path');
%             app.DataMatrixDropDown.Value=app.f3;
%             switch app.f3
%                 case 0
%                     app.phase=evalin('base','f_app_m');
%                 case 1
%                     app.phase=evalin('base','r_app_m');
%                 case 2
%                     app.phase=evalin('base','phase_app_avg_m');
%                 case 3
%                     app.phase=evalin('base','zmod_app_m');
%                 case 4
%                     app.phase=evalin('base','x_app_m');
%                     %the x_app_m data might be differnt for 2D structure,
%                     %do it later.
%             end
            
            % read all the variable to find the 3d matrix in the workspace.
            s=evalin('base','whos');
            s_size={s.size};
            %s_type={s.class};
            %s_matrix=strcmp(s_type,'double')||strcmp(s_type,'single');
            s_dim=[];
            for i=1:length(s_size)
                [~,s_dim(i)]=size(s_size{i});
            end
            s_3d=(s_dim==3);
            s_3d_matrix=s_3d;%.*s_matrix;
            s_cell1={s.name};
            cell_list=s_cell1(logical(s_3d_matrix));     %get a full list of all the 3d matrix in the workspace.
            app.DataMatrixDropDown.Items=cell_list;
            app.phase=evalin('base',app.DataMatrixDropDown.Value);
            n=str2double(app.DimensionDropDown.Value);
            dimen=size(app.phase);
            dim=[1 2 3];
            limit=dimen(n);
            app.sliceindexSlider.Limits=[1,limit];
            if n~=3
                app.verticalaxisSlider.Limits=[1,size(app.phase,min(dim(dim~=n)))];
                app.horrizontalaxisSlider.Limits=[1,size(app.phase,max(dim(dim~=n)))];
            else
                app.verticalaxisSlider.Limits=[1,size(app.phase,max(dim(dim~=n)))];
                app.horrizontalaxisSlider.Limits=[1,size(app.phase,min(dim(dim~=n)))];
            end
            title(app.vertical,'vertical line profile');
            title(app.horrizontal,'horrizontal line profile');
            s0=app.DataMatrixDropDown.Value;
            s1=extractBefore(s0,"_");
            if ~isempty(s1)
                title(app.UIAxes,s1);
            else
                title(app.UIAxes,s0);
            end
            
            % set the portion of the display range.
            app.sigma.Value=1;

            app.matrix_max=max(app.phase,[],'all','omitmissing');
            app.matrix_min=min(app.phase,[],'all','omitmissing');
        end

        % Value changed function: DimensionDropDown
        function DimensionDropDownValueChanged(app, event)
            value = str2double(app.DimensionDropDown.Value);
            dim=[1,2,3];
            app.sliceindexSlider.Limits=[1,size(app.phase,value)];
            if value~=3
                app.verticalaxisSlider.Limits=[1,size(app.phase,min(dim(dim~=value)))];
                app.horrizontalaxisSlider.Limits=[1,size(app.phase,max(dim(dim~=value)))];
            else
                app.verticalaxisSlider.Limits=[1,size(app.phase,max(dim(dim~=value)))];
                app.horrizontalaxisSlider.Limits=[1,size(app.phase,min(dim(dim~=value)))];
            end
            plot3D(app);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if app.IndexCheck.Value==1
                value = app.sliceindexSlider.Value;
                limit = app.sliceindexSlider.Limits;
            else
                if app.HorzCheck.Value==1
                    value = app.horrizontalaxisSlider.Value;
                    limit = app.horrizontalaxisSlider.Limits;
                else
                    value = app.verticalaxisSlider.Value;
                    limit = app.verticalaxisSlider.Limits;
                end
            end
            min=limit(1);
            max=limit(2);
            if strcmp(key,'leftarrow')
                if value-1>=min
                	value = value-1; % left value
                end
            elseif strcmp(key,'rightarrow')
                if value+1<=max
                    value = value+1; % right value
                end
            end
            if app.IndexCheck.Value==1
                app.sliceindexSlider.Value=value;
                sliceindexSliderValueChanged(app);
                app.slice.Value=value;
            else
                if app.HorzCheck.Value==1
                    app.horrizontalaxisSlider.Value=value;
                    horrizontalaxisSliderValueChanged(app);
                    app.h_index.Value=value;
                else
                    app.verticalaxisSlider.Value=value;
                    verticalaxisSliderValueChanged(app);
                    app.v_index.Value=value;
                end
            end
            
        end

        % Value changed function: sliceindexSlider
        function sliceindexSliderValueChanged(app, event)
            value =round(app.sliceindexSlider.Value,0);
            app.sliceindexSlider.Value=value;
            plot3D(app);
            app.slice.Value=value;
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            path1=app.DataMatrixDropDown.Value;
            path2=['_3D_result_',path1,'.tiff'];
            path0=[app.path(1:end-4),path2];
            app.EditField.Value=path0;
            answer='0';
            if isfile(path0)
                answer=questdlg('The file already exists, do you want to replace it?','Replace file?','Yes!','No!','No!');
            end
            if strcmp(answer,'Yes!')||~isfile(path0)
                delete(path0);
                for i=1:size(app.phase, 1)
                    imwrite(mat2gray(squeeze(app.phase(i, :, :))), path0, 'WriteMode', 'append');
                end
            end
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            % try refreshing the list first before load.
            % refresh the list of 3d matrix.
            s=evalin('base','whos');
            s_size={s.size};
            %s_type={s.class};
            %s_matrix=strcmp(s_type,'double');
            s_dim=[];
            for i=1:length(s_size)
                [~,s_dim(i)]=size(s_size{i});
            end
            s_3d=(s_dim==3);
            s_3d_matrix=s_3d;%.*s_matrix;
            s_cell1={s.name};
            cell_list=s_cell1(logical(s_3d_matrix));     %get a full list of all the 3d matrix in the workspace.
            app.DataMatrixDropDown.Items=cell_list;

            app.f3=app.DataMatrixDropDown.Value;
            app.path=evalin('base','path');
%             switch app.f3
%                 case 0
%                     app.phase=evalin('base','f_app_m');
%                 case 1
%                     app.phase=evalin('base','r_app_m');
%                 case 2
%                     app.phase=evalin('base','phase_app_avg_m');
%                 case 3
%                     app.phase=evalin('base','zmod_app_m');
%                 case 4
%                     app.phase=evalin('base','x_app_m');
%                     %the x_app_m data might be differnt for 2D structure,
%                     %do it later.
%             end
            % to read the matrix according to the value given in the
            % dropdown box.
            app.phase=evalin('base',app.f3);
            n=str2double(app.DimensionDropDown.Value);
            dimen=size(app.phase);
            limit=dimen(n);
            app.sliceindexSlider.Limits=[1,limit];
            dim=[1 2 3];
            if n~=3
                app.verticalaxisSlider.Limits=[1,size(app.phase,min(dim(dim~=n)))];
                app.horrizontalaxisSlider.Limits=[1,size(app.phase,max(dim(dim~=n)))];
            else
                app.verticalaxisSlider.Limits=[1,size(app.phase,max(dim(dim~=n)))];
                app.horrizontalaxisSlider.Limits=[1,size(app.phase,min(dim(dim~=n)))];
            end
            s0=app.DataMatrixDropDown.Value;
            s1=extractBefore(s0,"_");
            if ~isempty(s1)
                title(app.UIAxes,s1);
            else
                title(app.UIAxes,s0);
            end

            app.matrix_max=max(app.phase,[],'all','omitmissing');
            app.matrix_min=min(app.phase,[],'all','omitmissing');
        end

        % Value changed function: DataMatrixDropDown
        function DataMatrixDropDownValueChanged(app, event)
            app.f3 = app.DataMatrixDropDown.Value;
            switch app.f3
                case 0
                    app.phase=evalin('base','f_app_m');
                case 1
                    app.phase=evalin('base','r_app_m');
                case 2
                    app.phase=evalin('base','phase_app_avg_m');
                case 3
                    app.phase=evalin('base','zmod_app_m');
                case 4
                    app.phase=evalin('base','x_app_m');
                    %the x_app_m data might be differnt for 2D structure,
                    %do it later.
            end
            n=str2double(app.DimensionDropDown.Value);
            dimen=size(app.phase);
            dim=[1 2 3];
            limit=dimen(n);
            app.sliceindexSlider.Limits=[1,limit];
            if n~=3
                app.verticalaxisSlider.Limits=[1,size(app.phase,min(dim(dim~=n)))];
                app.horrizontalaxisSlider.Limits=[1,size(app.phase,max(dim(dim~=n)))];
            else
                app.verticalaxisSlider.Limits=[1,size(app.phase,max(dim(dim~=n)))];
                app.horrizontalaxisSlider.Limits=[1,size(app.phase,min(dim(dim~=n)))];
            end
            loadButtonPushed(app);
            plot3D(app);
        end

        % Button pushed function: loadtifButton
        function loadtifButtonPushed(app, event)
            %ask for input of the file information.
            [file0,path0]=uigetfile(app.loadtifpath);
            app.loadtifpath=[path0,file0];
            s=tiffreadVolume(app.loadtifpath);
            app.phase=double(s);
            n=str2double(app.DimensionDropDown.Value);
            dimen=size(app.phase);
            limit=dimen(n);
            app.sliceindexSlider.Limits=[1,limit];
            dim=[1 2 3];
            if n~=3
                app.verticalaxisSlider.Limits=[1,size(app.phase,min(dim(dim~=n)))];
                app.horrizontalaxisSlider.Limits=[1,size(app.phase,max(dim(dim~=n)))];
            else
                app.verticalaxisSlider.Limits=[1,size(app.phase,max(dim(dim~=n)))];
                app.horrizontalaxisSlider.Limits=[1,size(app.phase,min(dim(dim~=n)))];
            end
            s0=app.DataMatrixDropDown.Value;
            s1=extractBefore(s0,"_");
            if ~isempty(s1)
                title(app.UIAxes,s1);
            else
                title(app.UIAxes,s0);
            end

            % update the max and min value of the given matrix.
            app.matrix_max=max(app.phase,[],'all','omitmissing');
            app.matrix_min=min(app.phase,[],'all','omitmissing');
        end

        % Value changed function: slice
        function sliceValueChanged(app, event)
            value = roundn(app.slice.Value,0);
            if value>=app.slice.Limits(1)&&value<=app.slice.Limits(2)
                app.sliceindexSlider.Value=value;
                sliceindexSliderValueChanged(app);
            else
                app.slice.Value=app.sliceindexSlider.Value;
            end
        end

        % Value changed function: IndexCheck
        function IndexCheckValueChanged(app, event)
            value = app.IndexCheck.Value;
            if value==1
                app.HorzCheck.Value=0;
                app.VertCheck.Value=0;
            else
                app.IndexCheck.Value=1;
            end
        end

        % Value changed function: HorzCheck
        function HorzCheckValueChanged(app, event)
            value = app.HorzCheck.Value;
            if value==1
                app.IndexCheck.Value=0;
                app.VertCheck.Value=0;
            else
                app.HorzCheck.Value=1;
            end
        end

        % Value changed function: VertCheck
        function VertCheckValueChanged(app, event)
            value = app.VertCheck.Value;
            if value==1
                app.IndexCheck.Value=0;
                app.HorzCheck.Value=0;
            else
                app.VertCheck.Value=1;
            end
        end

        % Value changed function: horrizontalaxisSlider
        function horrizontalaxisSliderValueChanged(app, event)
            value1=roundn(app.verticalaxisSlider.Value,0);
            value=roundn(app.horrizontalaxisSlider.Value,0);
            [m,n]=size(app.data0);
            plot(app.horrizontal,1:n,app.data0(value,:));
            imagesc(app.UIAxes,app.data0);
            axis(app.UIAxes,"xy");
            hold(app.UIAxes,'on');
            plot(app.UIAxes,1:n,value*ones(n,1),'color','r');
            plot(app.UIAxes,value1*ones(m,1),1:m,'color','b');
            hold(app.UIAxes,'off');
            axis(app.UIAxes,"tight");
            if app.DimensionDropDown.Value==3
                axis(app.UIAxes,"equal");
            else
                axis(app.UIAxes,"square");
            end
            m=xlim(app.UIAxes);
            xlim(app.horrizontal,m);
            %trying to match the labels and ticks.
            xlabel(app.horrizontal,app.UIAxes.XLabel.String);
            ylabel(app.horrizontal,app.UIAxes.Title.String);
            app.h_index.Value=value;
            %matching ticks.
        end

        % Value changed function: verticalaxisSlider
        function verticalaxisSliderValueChanged(app, event)
            value = roundn(app.verticalaxisSlider.Value,0);
            value1=roundn(app.horrizontalaxisSlider.Value,0);
            [m,n]=size(app.data0);
            plot(app.vertical,app.data0(:,value),1:m);
            imagesc(app.UIAxes,app.data0);
            axis(app.UIAxes,"xy");
            axis(app.vertical,"xy");
            hold(app.UIAxes,'on');
            plot(app.UIAxes,value*ones(m,1),1:m,'color','b');
            plot(app.UIAxes,1:n,value1*ones(n,1),'color','r');
            hold(app.UIAxes,'off');
            axis(app.UIAxes,"tight");
            if app.DimensionDropDown.Value==3
                axis(app.UIAxes,"equal");
            else
                axis(app.UIAxes,"square");
            end
            m=ylim(app.UIAxes);
            ylim(app.vertical,m);
            %matching labels.
            xlabel(app.vertical,app.UIAxes.Title.String);
            ylabel(app.vertical,app.UIAxes.YLabel.String);
            app.v_index.Value=value;

            % maintain the color limits.
            clim(app.UIAxes,[app.matrix_min,app.matrix_max].*app.sigma.Value);
        end

        % Value changed function: h_index
        function h_indexValueChanged(app, event)
            value = roundn(app.h_index.Value,0);
            if value>=app.horrizontalaxisSlider.Limits(1)&&value<=app.horrizontalaxisSlider.Limits(2)
                app.horrizontalaxisSlider.Value=value;
                horrizontalaxisSliderValueChanged(app);
            else
                app.h_index.Value=app.horrizontalaxisSlider.Value;
            end
        end

        % Value changed function: v_index
        function v_indexValueChanged(app, event)
            value = roundn(app.v_index.Value,0);
            if value>=app.verticalaxisSlider.Limits(1)&&value<=app.verticalaxisSlider.Limits(2)
                app.verticalaxisSlider.Value=value;
                verticalaxisSliderValueChanged(app);
            else
                app.v_index.Value=app.verticalaxisSlider.Value;
            end
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            value = app.ColormapDropDown.Value;
            if strcmp(value,'slan_CM')
                try
                    app.colormap_slanCM=slanCM(app.slan_CM_type.Value);
                catch
                    % kindly remind slanCM doesn't work
                    errordlg('slanCM function is not working properly, please check.');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.horrizontal = uiaxes(app.UIFigure);
            title(app.horrizontal, 'Title')
            xlabel(app.horrizontal, 'X')
            ylabel(app.horrizontal, 'Y')
            zlabel(app.horrizontal, 'Z')
            app.vertical = uiaxes(app.UIFigure);
            title(app.vertical, 'Title')
            xlabel(app.vertical, 'X')
            ylabel(app.vertical, 'Y')
            zlabel(app.vertical, 'Z')
            app.sliceindexSliderLabel = uilabel(app.UIFigure);
            app.sliceindexSliderLabel.Text = 'slice index';
            app.sliceindexSlider = uislider(app.UIFigure);
            app.sliceindexSlider.ValueChangedFcn = createCallbackFcn(app, @sliceindexSliderValueChanged, true);
            app.DimensionDropDownLabel = uilabel(app.UIFigure);
            app.DimensionDropDownLabel.Text = 'Dimension';
            app.DimensionDropDown = uidropdown(app.UIFigure);
            app.DimensionDropDown.Items = {'x', 'y', 'z'};
            app.DimensionDropDown.ItemsData = {'2', '1', '3'};
            app.DimensionDropDown.ValueChangedFcn = createCallbackFcn(app, @DimensionDropDownValueChanged, true);
            app.DimensionDropDown.Value = '1';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.smoothSwitchLabel = uilabel(app.UIFigure);
            app.smoothSwitchLabel.Text = 'smooth';
            app.smoothSwitch = uiswitch(app.UIFigure, 'toggle');
            app.smoothSwitch.ItemsData = [0 1];
            app.smoothSwitch.Value = 0;
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.slice = uieditfield(app.UIFigure, 'numeric');
            app.slice.ValueChangedFcn = createCallbackFcn(app, @sliceValueChanged, true);
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.horrizontalaxisSliderLabel = uilabel(app.UIFigure);
            app.horrizontalaxisSliderLabel.Text = 'horrizontal axis';
            app.horrizontalaxisSlider = uislider(app.UIFigure);
            app.horrizontalaxisSlider.ValueChangedFcn = createCallbackFcn(app, @horrizontalaxisSliderValueChanged, true);
            app.verticalaxisSliderLabel = uilabel(app.UIFigure);
            app.verticalaxisSliderLabel.Text = 'vertical axis';
            app.verticalaxisSlider = uislider(app.UIFigure);
            app.verticalaxisSlider.ValueChangedFcn = createCallbackFcn(app, @verticalaxisSliderValueChanged, true);
            app.DataMatrixDropDownLabel = uilabel(app.UIFigure);
            app.DataMatrixDropDownLabel.Text = 'Data Matrix';
            app.DataMatrixDropDown = uidropdown(app.UIFigure);
            app.DataMatrixDropDown.Items = {'Force', 'Amplitude', 'Phase', 'Modulation', 'Xposition'};
            app.DataMatrixDropDown.ValueChangedFcn = createCallbackFcn(app, @DataMatrixDropDownValueChanged, true);
            app.DataMatrixDropDown.Value = 'Force';
            app.h_index = uieditfield(app.UIFigure, 'numeric');
            app.h_index.ValueChangedFcn = createCallbackFcn(app, @h_indexValueChanged, true);
            app.v_index = uieditfield(app.UIFigure, 'numeric');
            app.v_index.ValueChangedFcn = createCallbackFcn(app, @v_indexValueChanged, true);
            app.loadtifButton = uibutton(app.UIFigure, 'push');
            app.loadtifButton.ButtonPushedFcn = createCallbackFcn(app, @loadtifButtonPushed, true);
            app.loadtifButton.Text = 'load tif file';
            app.sigma = uieditfield(app.UIFigure, 'numeric');
            app.sigma.Tooltip = {'portion of the min and max to be displayed'};
            app.sigma.Value = 1;
            app.IndexCheck = uicheckbox(app.UIFigure);
            app.IndexCheck.ValueChangedFcn = createCallbackFcn(app, @IndexCheckValueChanged, true);
            app.IndexCheck.Text = '';
            app.IndexCheck.Value = true;
            app.HorzCheck = uicheckbox(app.UIFigure);
            app.HorzCheck.ValueChangedFcn = createCallbackFcn(app, @HorzCheckValueChanged, true);
            app.HorzCheck.Text = '';
            app.VertCheck = uicheckbox(app.UIFigure);
            app.VertCheck.ValueChangedFcn = createCallbackFcn(app, @VertCheckValueChanged, true);
            app.VertCheck.Text = '';
            app.ColormapDropDownLabel = uilabel(app.UIFigure);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.UIFigure);
            app.ColormapDropDown.Items = {'turbo', 'water_density_blue', 'water_orientation', 'water_orientation_narrow', 'slan_CM'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'turbo';
            app.invert = uicheckbox(app.UIFigure);
            app.invert.Text = 'Invert';
            app.slan_CM_type = uieditfield(app.UIFigure, 'text');
            app.slan_CM_type.Tooltip = {'Please refer to the slanCM folder for the type names.'};
            app.slan_CM_type.Value = 'twilight';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = display_3D_curve_all

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end